classdef simulasiModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SimulasiPemodelanMatematikaPanel  matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        Panel_3                        matlab.ui.container.Panel
        SIMULASIPEMODELANMATEMATIKALabel_3  matlab.ui.control.Label
        SIMULASIPEMODELANMATEMATIKALabel_5  matlab.ui.control.Label
        SIMULASIPEMODELANMATEMATIKALabel_4  matlab.ui.control.Label
        SIMULASIPEMODELANMATEMATIKALabel_6  matlab.ui.control.Label
        DEPARTEMENMATEMATIKALabel      matlab.ui.control.Label
        SIMULASIPEMODELANMATEMATIKALabel_2  matlab.ui.control.Label
        SIMULASIPEMODELANMATEMATIKALabel  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        SimulationTab                  matlab.ui.container.Tab
        GrafikPanel                    matlab.ui.container.Panel
        Panel_2                        matlab.ui.container.Panel
        PlotGabungan                   matlab.ui.control.Button
        GrafikGabunganLabel            matlab.ui.control.Label
        PlotR                          matlab.ui.control.Button
        PopulasiRecoveredLabel         matlab.ui.control.Label
        PlotI                          matlab.ui.control.Button
        PlotS                          matlab.ui.control.Button
        PopulasiInfectedLabel          matlab.ui.control.Label
        PopulasiSusceptibleLabel       matlab.ui.control.Label
        PopulasiVaccinatedLabel        matlab.ui.control.Label
        PlotV                          matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        SIMULASIMODELLabel             matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        NilaiR0Panel                   matlab.ui.container.Panel
        R0                             matlab.ui.control.NumericEditField
        RoLabel                        matlab.ui.control.Label
        IterasiPanel                   matlab.ui.container.Panel
        n                              matlab.ui.control.NumericEditField
        MasukkanBanyaknyaIterasiEditFieldLabel  matlab.ui.control.Label
        WaktuPanel                     matlab.ui.container.Panel
        tf                             matlab.ui.control.NumericEditField
        WaktuAkhirtfEditFieldLabel_2   matlab.ui.control.Label
        t0                             matlab.ui.control.NumericEditField
        WaktuAwalt0EditFieldLabel_2    matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ParameterPanel                 matlab.ui.container.Panel
        b                              matlab.ui.control.NumericEditField
        bEditFieldLabel_2              matlab.ui.control.Label
        p                              matlab.ui.control.NumericEditField
        pEditFieldLabel_2              matlab.ui.control.Label
        gamma                          matlab.ui.control.NumericEditField
        LajuKesembuhanPenyakitEditFieldLabel_2  matlab.ui.control.Label
        beta                           matlab.ui.control.NumericEditField
        PeluangIndividuTerinfeksiEditFieldLabel_2  matlab.ui.control.Label
        alpha                          matlab.ui.control.NumericEditField
        LajuKehilanganImunitasEditFieldLabel_2  matlab.ui.control.Label
        m2                             matlab.ui.control.NumericEditField
        LajuKematianEditFieldLabel     matlab.ui.control.Label
        m1                             matlab.ui.control.NumericEditField
        LajuKelahiranEditFieldLabel_2  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function m1ValueChanged(app, event)
            app.m1 = app.m1.Value;
            
        end

        % Callback function
        function m2ValueChanged(app, event)
            value = app.m2.Value;
            
        end

        % Callback function
        function m2ValueChanged2(app, event)

            
        end

        % Button pushed function: PlotV
        function PlotVPushed(app, event)
            %------------------ inisialisasi parameter --------------   
            t0 = app.t0.Value;
            tf = app.tf.Value;
            n = app.n.Value;
            
            m1 = app.m1.Value;
            m2 = app.m2.Value;
            alpha = app.alpha.Value;
            beta = app.beta.Value;
            gamma = app.gamma.Value;
            p = app.p.Value;
            b = app.b.Value;

            h=(tf-t0)/n;
            t=0:h:n*h;
            hh=1/2;

            %---------------------- variable state ---------------------
            V=zeros(n+1,1);
            S=zeros(n+1,1);
            I=zeros(n+1,1);
            R=zeros(n+1,1);
            
            %---------------- inisialisasi variabel state --------------
            V(1)=0.2; 
            S(1)=0.5;
            I(1)=0.2;
            R(1)=0.1;
            N(1)=V(1)+S(1)+I(1)+R(1);
            %----------------- Basic Reproduction Number ---------------
            Ro=(beta*b*alpha*m1+(1-p)*m1*beta*m2)/(m2*(m2+gamma)*(b*alpha+m2));
            %Menampilkan hasil perhitungan Ro
            app.R0.Value=Ro;
            %------------- Representasi Metode Runge-Kutta -------------
            for i=1:n
            %Step 1
            K1_V(i)= h*(p*m1-b*alpha*V(i)-m2*V(i));
            K1_S(i)=h*(b*alpha*V(i)+(1-p)*m1+alpha*R(i)-m2*S(i)-beta*S(i)*I(i));
            K1_I(i)=h*(beta*S(i)*I(i)-(m2+gamma)*I(i));
            K1_R(i)=h*(gamma*I(i)-alpha*R(i)-m2*R(i));
            %Step2
            K2_V(i)= h*(p*m1-b*alpha*(V(i)+hh*K1_V(i))-m2*(V(i)+hh*K1_V(i)));
            K2_S(i)=h*(b*alpha*(V(i)+hh*K1_V(i))+(1-p)*m1+alpha*(R(i)+hh*K1_R(i))-m2*(S(i)+hh*K1_S(i))-beta*(S(i)+hh*K1_S(i))*(I(i)+hh*K1_I(i)));
            K2_I(i)=h*(beta*(S(i)+hh*K1_S(i))*(I(i)+hh*K1_I(i))-(m2+gamma)*(I(i)+hh*K1_I(i)));
            K2_R(i)=h*(gamma*(I(i)+hh*K1_I(i))-alpha*(R(i)+hh*K1_R(i))-m2*(R(i)+hh*K1_R(i)));
            %Step3
            K3_V(i)= h*(p*m1-b*alpha*(V(i)+hh*K2_V(i))-m2*(V(i)+hh*K2_V(i)));
            K3_S(i)=h*(b*alpha*(V(i)+hh*K2_V(i))+(1-p)*m1+alpha*(R(i)+hh*K2_R(i))-m2*(S(i)+hh*K2_S(i))-beta*(S(i)+hh*K2_S(i))*(I(i)+hh*K2_I(i)));
            K3_I(i)=h*(beta*(S(i)+hh*K2_S(i))*(I(i)+hh*K2_I(i))-(m2+gamma)*(I(i)+hh*K2_I(i)));
            K3_R(i)=h*(gamma*(I(i)+hh*K2_I(i))-alpha*(R(i)+hh*K2_R(i))-m2*(R(i)+hh*K2_R(i)));
            %Step4
            K4_V(i)= h*(p*m1-b*alpha*(V(i)+K3_V(i))-m2*(V(i)+K3_V(i)));
            K4_S(i)=h*(b*alpha*(V(i)+K3_V(i))+(1-p)*m1+alpha*(R(i)+K3_R(i))-m2*(S(i)+K3_S(i))-beta*(S(i)+K3_S(i))*(I(i)+K3_I(i)));
            K4_I(i)=h*(beta*(S(i)+K3_S(i))*(I(i)+K3_I(i))-(m2+gamma)*(I(i)+K3_I(i)));
            K4_R(i)=h*(gamma*(I(i)+K3_I(i))-alpha*(R(i)+K3_R(i))-m2*(R(i)+K3_R(i)));
            %Total 
            V(i+1) = V(i) + (1/6)*(K1_V(i) + (2*K2_V(i)) + (2*K3_V(i)) + K4_V(i)); 
            S(i+1) = S(i) + (1/6)*(K1_S(i) + (2*K2_S(i)) + (2*K3_S(i)) + K4_S(i));
            I(i+1) = I(i) + (1/6)*(K1_I(i) + (2*K2_I(i)) + (2*K3_I(i)) + K4_I(i));
            R(i+1) = R(i) + (1/6)*(K1_R(i) + (2*K2_R(i)) + (2*K3_R(i)) + K4_R(i));
            end

            
            plot(app.UIAxes,t,V,'r','LineWidth',2)
            legend(app.UIAxes, 'V')
            hold(app.UIAxes, 'off')
            
            
        end

        % Value changed function: m1
        function m1ValueChanged2(app, event)
            m1=app.m1.Value;
            
        end

        % Value changed function: m2
        function m2ValueChanged3(app, event)
            m2= app.m2.Value;
            
        end

        % Value changed function: alpha
        function alphaValueChanged(app, event)
            alpha= app.alpha.Value;
            
        end

        % Value changed function: beta
        function betaValueChanged(app, event)
            beta = app.beta.Value;
            
        end

        % Value changed function: gamma
        function gammaValueChanged(app, event)
            gamma= app.gamma.Value;
            
        end

        % Value changed function: p
        function pValueChanged(app, event)
            p = app.p.Value;
            
        end

        % Value changed function: b
        function bValueChanged(app, event)
            b= app.b.Value;
            
        end

        % Value changed function: t0
        function t0ValueChanged(app, event)
            t0= app.t0.Value;
            
        end

        % Value changed function: tf
        function tfValueChanged(app, event)
            tf= app.tf.Value;
            
        end

        % Value changed function: n
        function nValueChanged(app, event)
            n= app.n.Value;
            
        end

        % Button pushed function: PlotS
        function PlotSButtonPushed(app, event)
            %------------------ inisialisasi parameter --------------   
            t0 = app.t0.Value;
            tf = app.tf.Value;
            n = app.n.Value;
            
            m1 = app.m1.Value;
            m2 = app.m2.Value;
            alpha = app.alpha.Value;
            beta = app.beta.Value;
            gamma = app.gamma.Value;
            p = app.p.Value;
            b = app.b.Value;

            h=(tf-t0)/n;
            t=0:h:n*h;
            hh=1/2;

            %---------------------- variable state ---------------------
            V=zeros(n+1,1);
            S=zeros(n+1,1);
            I=zeros(n+1,1);
            R=zeros(n+1,1);
            
            %%---------------- inisialisasi variabel state --------------
            V(1)=0.2; 
            S(1)=0.5;
            I(1)=0.2;
            R(1)=0.1;
            N(1)=V(1)+S(1)+I(1)+R(1);
            %----------------- Basic Reproduction Number ---------------
            Ro=(beta*b*alpha*m1+(1-p)*m1*beta*m2)/(m2*(m2+gamma)*(b*alpha+m2));
            %Menampilkan hasil perhitungan Ro
            app.R0.Value=Ro;
            %------------- Representasi Metode Runge-Kutta -------------
            for i=1:n
            %Step 1
            K1_V(i)= h*(p*m1-b*alpha*V(i)-m2*V(i));
            K1_S(i)=h*(b*alpha*V(i)+(1-p)*m1+alpha*R(i)-m2*S(i)-beta*S(i)*I(i));
            K1_I(i)=h*(beta*S(i)*I(i)-(m2+gamma)*I(i));
            K1_R(i)=h*(gamma*I(i)-alpha*R(i)-m2*R(i));
            %Step2
            K2_V(i)= h*(p*m1-b*alpha*(V(i)+hh*K1_V(i))-m2*(V(i)+hh*K1_V(i)));
            K2_S(i)=h*(b*alpha*(V(i)+hh*K1_V(i))+(1-p)*m1+alpha*(R(i)+hh*K1_R(i))-m2*(S(i)+hh*K1_S(i))-beta*(S(i)+hh*K1_S(i))*(I(i)+hh*K1_I(i)));
            K2_I(i)=h*(beta*(S(i)+hh*K1_S(i))*(I(i)+hh*K1_I(i))-(m2+gamma)*(I(i)+hh*K1_I(i)));
            K2_R(i)=h*(gamma*(I(i)+hh*K1_I(i))-alpha*(R(i)+hh*K1_R(i))-m2*(R(i)+hh*K1_R(i)));
            %Step3
            K3_V(i)= h*(p*m1-b*alpha*(V(i)+hh*K2_V(i))-m2*(V(i)+hh*K2_V(i)));
            K3_S(i)=h*(b*alpha*(V(i)+hh*K2_V(i))+(1-p)*m1+alpha*(R(i)+hh*K2_R(i))-m2*(S(i)+hh*K2_S(i))-beta*(S(i)+hh*K2_S(i))*(I(i)+hh*K2_I(i)));
            K3_I(i)=h*(beta*(S(i)+hh*K2_S(i))*(I(i)+hh*K2_I(i))-(m2+gamma)*(I(i)+hh*K2_I(i)));
            K3_R(i)=h*(gamma*(I(i)+hh*K2_I(i))-alpha*(R(i)+hh*K2_R(i))-m2*(R(i)+hh*K2_R(i)));
            %Step4
            K4_V(i)= h*(p*m1-b*alpha*(V(i)+K3_V(i))-m2*(V(i)+K3_V(i)));
            K4_S(i)=h*(b*alpha*(V(i)+K3_V(i))+(1-p)*m1+alpha*(R(i)+K3_R(i))-m2*(S(i)+K3_S(i))-beta*(S(i)+K3_S(i))*(I(i)+K3_I(i)));
            K4_I(i)=h*(beta*(S(i)+K3_S(i))*(I(i)+K3_I(i))-(m2+gamma)*(I(i)+K3_I(i)));
            K4_R(i)=h*(gamma*(I(i)+K3_I(i))-alpha*(R(i)+K3_R(i))-m2*(R(i)+K3_R(i)));
            %Total 
            V(i+1) = V(i) + (1/6)*(K1_V(i) + (2*K2_V(i)) + (2*K3_V(i)) + K4_V(i)); 
            S(i+1) = S(i) + (1/6)*(K1_S(i) + (2*K2_S(i)) + (2*K3_S(i)) + K4_S(i));
            I(i+1) = I(i) + (1/6)*(K1_I(i) + (2*K2_I(i)) + (2*K3_I(i)) + K4_I(i));
            R(i+1) = R(i) + (1/6)*(K1_R(i) + (2*K2_R(i)) + (2*K3_R(i)) + K4_R(i));
            end

            
            plot(app.UIAxes,t,S,'g','LineWidth',2)
            legend(app.UIAxes, 'S')
            hold(app.UIAxes, 'off')
            
        end

        % Button pushed function: PlotI
        function PlotIButtonPushed(app, event)
            %------------------ inisialisasi parameter --------------   
            t0 = app.t0.Value;
            tf = app.tf.Value;
            n = app.n.Value;
            
            m1 = app.m1.Value;
            m2 = app.m2.Value;
            alpha = app.alpha.Value;
            beta = app.beta.Value;
            gamma = app.gamma.Value;
            p = app.p.Value;
            b = app.b.Value;

            h=(tf-t0)/n;
            t=0:h:n*h;
            hh=1/2;

            %---------------------- variable state ---------------------
            V=zeros(n+1,1);
            S=zeros(n+1,1);
            I=zeros(n+1,1);
            R=zeros(n+1,1);
            
            %%---------------- inisialisasi variabel state --------------
            V(1)=0.2; 
            S(1)=0.5;
            I(1)=0.2;
            R(1)=0.1;
            N(1)=V(1)+S(1)+I(1)+R(1);
            %----------------- Basic Reproduction Number ---------------
            Ro=(beta*b*alpha*m1+(1-p)*m1*beta*m2)/(m2*(m2+gamma)*(b*alpha+m2));
            %Menampilkan hasil perhitungan Ro
            app.R0.Value=Ro;
            %------------- Representasi Metode Runge-Kutta -------------
            for i=1:n
            %Step 1
            K1_V(i)= h*(p*m1-b*alpha*V(i)-m2*V(i));
            K1_S(i)=h*(b*alpha*V(i)+(1-p)*m1+alpha*R(i)-m2*S(i)-beta*S(i)*I(i));
            K1_I(i)=h*(beta*S(i)*I(i)-(m2+gamma)*I(i));
            K1_R(i)=h*(gamma*I(i)-alpha*R(i)-m2*R(i));
            %Step2
            K2_V(i)= h*(p*m1-b*alpha*(V(i)+hh*K1_V(i))-m2*(V(i)+hh*K1_V(i)));
            K2_S(i)=h*(b*alpha*(V(i)+hh*K1_V(i))+(1-p)*m1+alpha*(R(i)+hh*K1_R(i))-m2*(S(i)+hh*K1_S(i))-beta*(S(i)+hh*K1_S(i))*(I(i)+hh*K1_I(i)));
            K2_I(i)=h*(beta*(S(i)+hh*K1_S(i))*(I(i)+hh*K1_I(i))-(m2+gamma)*(I(i)+hh*K1_I(i)));
            K2_R(i)=h*(gamma*(I(i)+hh*K1_I(i))-alpha*(R(i)+hh*K1_R(i))-m2*(R(i)+hh*K1_R(i)));
            %Step3
            K3_V(i)= h*(p*m1-b*alpha*(V(i)+hh*K2_V(i))-m2*(V(i)+hh*K2_V(i)));
            K3_S(i)=h*(b*alpha*(V(i)+hh*K2_V(i))+(1-p)*m1+alpha*(R(i)+hh*K2_R(i))-m2*(S(i)+hh*K2_S(i))-beta*(S(i)+hh*K2_S(i))*(I(i)+hh*K2_I(i)));
            K3_I(i)=h*(beta*(S(i)+hh*K2_S(i))*(I(i)+hh*K2_I(i))-(m2+gamma)*(I(i)+hh*K2_I(i)));
            K3_R(i)=h*(gamma*(I(i)+hh*K2_I(i))-alpha*(R(i)+hh*K2_R(i))-m2*(R(i)+hh*K2_R(i)));
            %Step4
            K4_V(i)= h*(p*m1-b*alpha*(V(i)+K3_V(i))-m2*(V(i)+K3_V(i)));
            K4_S(i)=h*(b*alpha*(V(i)+K3_V(i))+(1-p)*m1+alpha*(R(i)+K3_R(i))-m2*(S(i)+K3_S(i))-beta*(S(i)+K3_S(i))*(I(i)+K3_I(i)));
            K4_I(i)=h*(beta*(S(i)+K3_S(i))*(I(i)+K3_I(i))-(m2+gamma)*(I(i)+K3_I(i)));
            K4_R(i)=h*(gamma*(I(i)+K3_I(i))-alpha*(R(i)+K3_R(i))-m2*(R(i)+K3_R(i)));
            %Total 
            V(i+1) = V(i) + (1/6)*(K1_V(i) + (2*K2_V(i)) + (2*K3_V(i)) + K4_V(i)); 
            S(i+1) = S(i) + (1/6)*(K1_S(i) + (2*K2_S(i)) + (2*K3_S(i)) + K4_S(i));
            I(i+1) = I(i) + (1/6)*(K1_I(i) + (2*K2_I(i)) + (2*K3_I(i)) + K4_I(i));
            R(i+1) = R(i) + (1/6)*(K1_R(i) + (2*K2_R(i)) + (2*K3_R(i)) + K4_R(i));
            end

            
            plot(app.UIAxes,t,I,'b','LineWidth',2)
            legend(app.UIAxes, 'I')
            hold(app.UIAxes, 'off')
        end

        % Button pushed function: PlotR
        function PlotRButtonPushed(app, event)
            %------------------ inisialisasi parameter --------------   
            t0 = app.t0.Value;
            tf = app.tf.Value;
            n = app.n.Value;
            
            m1 = app.m1.Value;
            m2 = app.m2.Value;
            alpha = app.alpha.Value;
            beta = app.beta.Value;
            gamma = app.gamma.Value;
            p = app.p.Value;
            b = app.b.Value;

            h=(tf-t0)/n;
            t=0:h:n*h;
            hh=1/2;

            %---------------------- variable state ---------------------
            V=zeros(n+1,1);
            S=zeros(n+1,1);
            I=zeros(n+1,1);
            R=zeros(n+1,1);
            
            %---------------- inisialisasi variabel state --------------
            V(1)=0.2; 
            S(1)=0.5;
            I(1)=0.2;
            R(1)=0.1;
            N(1)=V(1)+S(1)+I(1)+R(1);
            %----------------- Basic Reproduction Number ---------------
            Ro=(beta*b*alpha*m1+(1-p)*m1*beta*m2)/(m2*(m2+gamma)*(b*alpha+m2));
            %Menampilkan hasil perhitungan Ro
            app.R0.Value=Ro;
            %------------- Representasi Metode Runge-Kutta -------------
            for i=1:n
            %Step 1
            K1_V(i)= h*(p*m1-b*alpha*V(i)-m2*V(i));
            K1_S(i)=h*(b*alpha*V(i)+(1-p)*m1+alpha*R(i)-m2*S(i)-beta*S(i)*I(i));
            K1_I(i)=h*(beta*S(i)*I(i)-(m2+gamma)*I(i));
            K1_R(i)=h*(gamma*I(i)-alpha*R(i)-m2*R(i));
            %Step2
            K2_V(i)= h*(p*m1-b*alpha*(V(i)+hh*K1_V(i))-m2*(V(i)+hh*K1_V(i)));
            K2_S(i)=h*(b*alpha*(V(i)+hh*K1_V(i))+(1-p)*m1+alpha*(R(i)+hh*K1_R(i))-m2*(S(i)+hh*K1_S(i))-beta*(S(i)+hh*K1_S(i))*(I(i)+hh*K1_I(i)));
            K2_I(i)=h*(beta*(S(i)+hh*K1_S(i))*(I(i)+hh*K1_I(i))-(m2+gamma)*(I(i)+hh*K1_I(i)));
            K2_R(i)=h*(gamma*(I(i)+hh*K1_I(i))-alpha*(R(i)+hh*K1_R(i))-m2*(R(i)+hh*K1_R(i)));
            %Step3
            K3_V(i)= h*(p*m1-b*alpha*(V(i)+hh*K2_V(i))-m2*(V(i)+hh*K2_V(i)));
            K3_S(i)=h*(b*alpha*(V(i)+hh*K2_V(i))+(1-p)*m1+alpha*(R(i)+hh*K2_R(i))-m2*(S(i)+hh*K2_S(i))-beta*(S(i)+hh*K2_S(i))*(I(i)+hh*K2_I(i)));
            K3_I(i)=h*(beta*(S(i)+hh*K2_S(i))*(I(i)+hh*K2_I(i))-(m2+gamma)*(I(i)+hh*K2_I(i)));
            K3_R(i)=h*(gamma*(I(i)+hh*K2_I(i))-alpha*(R(i)+hh*K2_R(i))-m2*(R(i)+hh*K2_R(i)));
            %Step4
            K4_V(i)= h*(p*m1-b*alpha*(V(i)+K3_V(i))-m2*(V(i)+K3_V(i)));
            K4_S(i)=h*(b*alpha*(V(i)+K3_V(i))+(1-p)*m1+alpha*(R(i)+K3_R(i))-m2*(S(i)+K3_S(i))-beta*(S(i)+K3_S(i))*(I(i)+K3_I(i)));
            K4_I(i)=h*(beta*(S(i)+K3_S(i))*(I(i)+K3_I(i))-(m2+gamma)*(I(i)+K3_I(i)));
            K4_R(i)=h*(gamma*(I(i)+K3_I(i))-alpha*(R(i)+K3_R(i))-m2*(R(i)+K3_R(i)));
            %Total 
            V(i+1) = V(i) + (1/6)*(K1_V(i) + (2*K2_V(i)) + (2*K3_V(i)) + K4_V(i)); 
            S(i+1) = S(i) + (1/6)*(K1_S(i) + (2*K2_S(i)) + (2*K3_S(i)) + K4_S(i));
            I(i+1) = I(i) + (1/6)*(K1_I(i) + (2*K2_I(i)) + (2*K3_I(i)) + K4_I(i));
            R(i+1) = R(i) + (1/6)*(K1_R(i) + (2*K2_R(i)) + (2*K3_R(i)) + K4_R(i));
            end

            
            plot(app.UIAxes,t,R,'m','LineWidth',2)
            legend(app.UIAxes, 'R')
            hold(app.UIAxes, 'off')
        end

        % Button pushed function: PlotGabungan
        function PlotGabunganButtonPushed(app, event)
            %------------------ inisialisasi parameter --------------   
            t0 = app.t0.Value;
            tf = app.tf.Value;
            n = app.n.Value;
            
            m1 = app.m1.Value;
            m2 = app.m2.Value;
            alpha = app.alpha.Value;
            beta = app.beta.Value;
            gamma = app.gamma.Value;
            p = app.p.Value;
            b = app.b.Value;

            h=(tf-t0)/n;
            t=0:h:n*h;
            hh=1/2;

            %---------------------- variable state ---------------------
            V=zeros(n+1,1);
            S=zeros(n+1,1);
            I=zeros(n+1,1);
            R=zeros(n+1,1);
            
            %---------------- inisialisasi variabel state --------------
            V(1)=0.2; 
            S(1)=0.5;
            I(1)=0.2;
            R(1)=0.1;
            N(1)=V(1)+S(1)+I(1)+R(1);
            %----------------- Basic Reproduction Number ---------------
            Ro=(beta*b*alpha*m1+(1-p)*m1*beta*m2)/(m2*(m2+gamma)*(b*alpha+m2));
            %Menampilkan hasil perhitungan Ro
            app.R0.Value=Ro;
            %------------- Representasi Metode Runge-Kutta -------------
            for i=1:n
            %Step 1
            K1_V(i)= h*(p*m1-b*alpha*V(i)-m2*V(i));
            K1_S(i)=h*(b*alpha*V(i)+(1-p)*m1+alpha*R(i)-m2*S(i)-beta*S(i)*I(i));
            K1_I(i)=h*(beta*S(i)*I(i)-(m2+gamma)*I(i));
            K1_R(i)=h*(gamma*I(i)-alpha*R(i)-m2*R(i));
            %Step2
            K2_V(i)= h*(p*m1-b*alpha*(V(i)+hh*K1_V(i))-m2*(V(i)+hh*K1_V(i)));
            K2_S(i)=h*(b*alpha*(V(i)+hh*K1_V(i))+(1-p)*m1+alpha*(R(i)+hh*K1_R(i))-m2*(S(i)+hh*K1_S(i))-beta*(S(i)+hh*K1_S(i))*(I(i)+hh*K1_I(i)));
            K2_I(i)=h*(beta*(S(i)+hh*K1_S(i))*(I(i)+hh*K1_I(i))-(m2+gamma)*(I(i)+hh*K1_I(i)));
            K2_R(i)=h*(gamma*(I(i)+hh*K1_I(i))-alpha*(R(i)+hh*K1_R(i))-m2*(R(i)+hh*K1_R(i)));
            %Step3
            K3_V(i)= h*(p*m1-b*alpha*(V(i)+hh*K2_V(i))-m2*(V(i)+hh*K2_V(i)));
            K3_S(i)=h*(b*alpha*(V(i)+hh*K2_V(i))+(1-p)*m1+alpha*(R(i)+hh*K2_R(i))-m2*(S(i)+hh*K2_S(i))-beta*(S(i)+hh*K2_S(i))*(I(i)+hh*K2_I(i)));
            K3_I(i)=h*(beta*(S(i)+hh*K2_S(i))*(I(i)+hh*K2_I(i))-(m2+gamma)*(I(i)+hh*K2_I(i)));
            K3_R(i)=h*(gamma*(I(i)+hh*K2_I(i))-alpha*(R(i)+hh*K2_R(i))-m2*(R(i)+hh*K2_R(i)));
            %Step4
            K4_V(i)= h*(p*m1-b*alpha*(V(i)+K3_V(i))-m2*(V(i)+K3_V(i)));
            K4_S(i)=h*(b*alpha*(V(i)+K3_V(i))+(1-p)*m1+alpha*(R(i)+K3_R(i))-m2*(S(i)+K3_S(i))-beta*(S(i)+K3_S(i))*(I(i)+K3_I(i)));
            K4_I(i)=h*(beta*(S(i)+K3_S(i))*(I(i)+K3_I(i))-(m2+gamma)*(I(i)+K3_I(i)));
            K4_R(i)=h*(gamma*(I(i)+K3_I(i))-alpha*(R(i)+K3_R(i))-m2*(R(i)+K3_R(i)));
            %Total 
            V(i+1) = V(i) + (1/6)*(K1_V(i) + (2*K2_V(i)) + (2*K3_V(i)) + K4_V(i)); 
            S(i+1) = S(i) + (1/6)*(K1_S(i) + (2*K2_S(i)) + (2*K3_S(i)) + K4_S(i));
            I(i+1) = I(i) + (1/6)*(K1_I(i) + (2*K2_I(i)) + (2*K3_I(i)) + K4_I(i));
            R(i+1) = R(i) + (1/6)*(K1_R(i) + (2*K2_R(i)) + (2*K3_R(i)) + K4_R(i));
            end

            plot(app.UIAxes,t,V,'r','LineWidth',2)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,t,S,'g','LineWidth',2)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,t,I,'b','LineWidth',2)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,t,R,'m','LineWidth',2)         
            legend(app.UIAxes, 'V','S','I','R')
            hold(app.UIAxes, 'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulasiPemodelanMatematikaPanel = uipanel(app.UIFigure);
            app.SimulasiPemodelanMatematikaPanel.Title = 'Simulasi Pemodelan Matematika';
            app.TabGroup = uitabgroup(app.SimulasiPemodelanMatematikaPanel);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo ITS-Biru.png');
            app.Image2 = uiimage(app.HomeTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo_matematika-its-besar.png');
            app.SIMULASIPEMODELANMATEMATIKALabel = uilabel(app.HomeTab);
            app.SIMULASIPEMODELANMATEMATIKALabel.Text = {'SIMULASI'; 'PEMODELAN MATEMATIKA'};
            app.SIMULASIPEMODELANMATEMATIKALabel_2 = uilabel(app.HomeTab);
            app.SIMULASIPEMODELANMATEMATIKALabel_2.Text = {'PEMODELAN SIR UNTUK PENYEBARAN PENYAKIT'; 'PERTUSIS DENGAN VAKSINASI PADA POPULASI MANUSIA'; 'KONSTAN'};
            app.Panel_3 = uipanel(app.HomeTab);
            app.DEPARTEMENMATEMATIKALabel = uilabel(app.Panel_3);
            app.DEPARTEMENMATEMATIKALabel.Text = {'DEPARTEMEN MATEMATIKA'; 'FAKULTAS SAINS DAN ANALITIKA DATA'; 'INSTITUT TEKNOLOGI SEPULUH NOPEMBER'; 'SURABAYA'; '2023'};
            app.SIMULASIPEMODELANMATEMATIKALabel_6 = uilabel(app.Panel_3);
            app.SIMULASIPEMODELANMATEMATIKALabel_6.Text = {'Dosen Pengampu :'; 'Prof. Dr. Dra. Mardlijah, MT'; 'Dr. Didik Khusnul Arif, S.Si, M.Si'};
            app.SIMULASIPEMODELANMATEMATIKALabel_4 = uilabel(app.Panel_3);
            app.SIMULASIPEMODELANMATEMATIKALabel_4.Text = {'Nuansa Cahaya Muhammad'; 'Fajar Wahyu Pratama'};
            app.SIMULASIPEMODELANMATEMATIKALabel_5 = uilabel(app.Panel_3);
            app.SIMULASIPEMODELANMATEMATIKALabel_5.Text = {'5002211015'; '5002211042'};
            app.SIMULASIPEMODELANMATEMATIKALabel_3 = uilabel(app.Panel_3);
            app.SIMULASIPEMODELANMATEMATIKALabel_3.Text = {'Disusun oleh :'; 'Kelompok 8'};
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Panel = uipanel(app.SimulationTab);
            app.ParameterPanel = uipanel(app.Panel);
            app.ParameterPanel.Title = 'Parameter';
            app.LajuKelahiranEditFieldLabel_2 = uilabel(app.ParameterPanel);
            app.LajuKelahiranEditFieldLabel_2.Text = 'Laju Kelahiran';
            app.m1 = uieditfield(app.ParameterPanel, 'numeric');
            app.m1.ValueChangedFcn = createCallbackFcn(app, @m1ValueChanged2, true);
            app.LajuKematianEditFieldLabel = uilabel(app.ParameterPanel);
            app.LajuKematianEditFieldLabel.Text = 'Laju Kematian';
            app.m2 = uieditfield(app.ParameterPanel, 'numeric');
            app.m2.ValueChangedFcn = createCallbackFcn(app, @m2ValueChanged3, true);
            app.LajuKehilanganImunitasEditFieldLabel_2 = uilabel(app.ParameterPanel);
            app.LajuKehilanganImunitasEditFieldLabel_2.Text = {'Laju Kehilangan '; 'Imunitas'};
            app.alpha = uieditfield(app.ParameterPanel, 'numeric');
            app.alpha.ValueChangedFcn = createCallbackFcn(app, @alphaValueChanged, true);
            app.PeluangIndividuTerinfeksiEditFieldLabel_2 = uilabel(app.ParameterPanel);
            app.PeluangIndividuTerinfeksiEditFieldLabel_2.Text = {'Peluang Individu'; 'Terinfeksi'};
            app.beta = uieditfield(app.ParameterPanel, 'numeric');
            app.beta.ValueChangedFcn = createCallbackFcn(app, @betaValueChanged, true);
            app.LajuKesembuhanPenyakitEditFieldLabel_2 = uilabel(app.ParameterPanel);
            app.LajuKesembuhanPenyakitEditFieldLabel_2.Text = {'Laju Kesembuhan'; 'Penyakit'};
            app.gamma = uieditfield(app.ParameterPanel, 'numeric');
            app.gamma.ValueChangedFcn = createCallbackFcn(app, @gammaValueChanged, true);
            app.pEditFieldLabel_2 = uilabel(app.ParameterPanel);
            app.pEditFieldLabel_2.Text = 'p';
            app.p = uieditfield(app.ParameterPanel, 'numeric');
            app.p.ValueChangedFcn = createCallbackFcn(app, @pValueChanged, true);
            app.bEditFieldLabel_2 = uilabel(app.ParameterPanel);
            app.bEditFieldLabel_2.Text = 'b';
            app.b = uieditfield(app.ParameterPanel, 'numeric');
            app.b.ValueChangedFcn = createCallbackFcn(app, @bValueChanged, true);
            app.Label = uilabel(app.Panel);
            app.Label.Text = {'Keterangan:'; 'p = Proporsi bayi baru lahir yang diberi vaksin'; 'b = Rasio kehilangan imunitas pada individu yang divaksin dibandingkan dengan'; '      kehilangan imunitas alami pada individu sembuh dari infeksi.'; ''; 'Note: Isi desimal dengan titik (.) bukan koma (,)'};
            app.WaktuPanel = uipanel(app.Panel);
            app.WaktuPanel.Title = 'Waktu';
            app.WaktuAwalt0EditFieldLabel_2 = uilabel(app.WaktuPanel);
            app.WaktuAwalt0EditFieldLabel_2.Text = 'Waktu Awal (t0)';
            app.t0 = uieditfield(app.WaktuPanel, 'numeric');
            app.t0.ValueChangedFcn = createCallbackFcn(app, @t0ValueChanged, true);
            app.WaktuAkhirtfEditFieldLabel_2 = uilabel(app.WaktuPanel);
            app.WaktuAkhirtfEditFieldLabel_2.Text = 'Waktu Akhir (tf)';
            app.tf = uieditfield(app.WaktuPanel, 'numeric');
            app.tf.ValueChangedFcn = createCallbackFcn(app, @tfValueChanged, true);
            app.IterasiPanel = uipanel(app.Panel);
            app.IterasiPanel.Title = 'Iterasi';
            app.MasukkanBanyaknyaIterasiEditFieldLabel = uilabel(app.IterasiPanel);
            app.MasukkanBanyaknyaIterasiEditFieldLabel.Text = {'Masukkan'; 'Banyaknya'; 'Iterasi'};
            app.n = uieditfield(app.IterasiPanel, 'numeric');
            app.n.ValueChangedFcn = createCallbackFcn(app, @nValueChanged, true);
            app.NilaiR0Panel = uipanel(app.Panel);
            app.NilaiR0Panel.Title = 'Nilai R0';
            app.RoLabel = uilabel(app.NilaiR0Panel);
            app.RoLabel.Text = 'Ro';
            app.R0 = uieditfield(app.NilaiR0Panel, 'numeric');
            app.SIMULASIMODELLabel = uilabel(app.SimulationTab);
            app.SIMULASIMODELLabel.Text = 'SIMULASI MODEL';
            app.GrafikPanel = uipanel(app.SimulationTab);
            app.GrafikPanel.Title = 'Grafik';
            app.UIAxes = uiaxes(app.GrafikPanel);
            title(app.UIAxes, 'Grafik')
            xlabel(app.UIAxes, 'Times')
            ylabel(app.UIAxes, 'Values')
            app.Panel_2 = uipanel(app.GrafikPanel);
            app.PlotV = uibutton(app.Panel_2, 'push');
            app.PlotV.ButtonPushedFcn = createCallbackFcn(app, @PlotVPushed, true);
            app.PlotV.Text = 'Plot';
            app.PopulasiVaccinatedLabel = uilabel(app.Panel_2);
            app.PopulasiVaccinatedLabel.Text = {'Populasi'; 'Vaccinated '};
            app.PopulasiSusceptibleLabel = uilabel(app.Panel_2);
            app.PopulasiSusceptibleLabel.Text = {'Populasi'; 'Susceptible '};
            app.PopulasiInfectedLabel = uilabel(app.Panel_2);
            app.PopulasiInfectedLabel.Text = {'Populasi'; 'Infected '};
            app.PlotS = uibutton(app.Panel_2, 'push');
            app.PlotS.ButtonPushedFcn = createCallbackFcn(app, @PlotSButtonPushed, true);
            app.PlotS.Text = 'Plot';
            app.PlotI = uibutton(app.Panel_2, 'push');
            app.PlotI.ButtonPushedFcn = createCallbackFcn(app, @PlotIButtonPushed, true);
            app.PlotI.Text = 'Plot';
            app.PopulasiRecoveredLabel = uilabel(app.Panel_2);
            app.PopulasiRecoveredLabel.Text = {'Populasi'; 'Recovered'};
            app.PlotR = uibutton(app.Panel_2, 'push');
            app.PlotR.ButtonPushedFcn = createCallbackFcn(app, @PlotRButtonPushed, true);
            app.PlotR.Text = 'Plot';
            app.GrafikGabunganLabel = uilabel(app.Panel_2);
            app.GrafikGabunganLabel.Text = {'Grafik'; 'Gabungan'};
            app.PlotGabungan = uibutton(app.Panel_2, 'push');
            app.PlotGabungan.ButtonPushedFcn = createCallbackFcn(app, @PlotGabunganButtonPushed, true);
            app.PlotGabungan.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulasiModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end